classdef Assignment_ENGN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Label_item                      matlab.ui.control.Label
        THISISTHELabel                  matlab.ui.control.Label
        ConnectedComponentLabelButton   matlab.ui.control.Button
        Click3stepsontheleftconsecutivelytoclassifytheorginalimageLabel  matlab.ui.control.Label
        ClassificationstepPanel         matlab.ui.container.Panel
        Label8_3                        matlab.ui.control.Label
        Label7_3                        matlab.ui.control.Label
        Label6_3                        matlab.ui.control.Label
        Label5_3                        matlab.ui.control.Label
        Label3_3                        matlab.ui.control.Label
        Label4_3                        matlab.ui.control.Label
        Label8_2                        matlab.ui.control.Label
        Label8                          matlab.ui.control.Label
        psi2Label                       matlab.ui.control.Label
        psi1Label                       matlab.ui.control.Label
        ActualvalLabel                  matlab.ui.control.Label
        Label7_2                        matlab.ui.control.Label
        Label6_2                        matlab.ui.control.Label
        Label5_2                        matlab.ui.control.Label
        Label4_2                        matlab.ui.control.Label
        Label7                          matlab.ui.control.Label
        Label6                          matlab.ui.control.Label
        Label5                          matlab.ui.control.Label
        Label4                          matlab.ui.control.Label
        Label3_2                        matlab.ui.control.Label
        Label3                          matlab.ui.control.Label
        HoleTotalareaLabel              matlab.ui.control.Label
        ClosestTrainingvalueLabel       matlab.ui.control.Label
        NormalizedLabel                 matlab.ui.control.Label
        circularityLabel                matlab.ui.control.Label
        RILabel                         matlab.ui.control.Label
        momentinvariantsLabel           matlab.ui.control.Label
        noholesLabel                    matlab.ui.control.Label
        ComputeformparametersandClassifyButton  matlab.ui.control.Button
        PreprocessingButton             matlab.ui.control.Button
        AuthorDuyAnhNguyennguyenanhduy22101998gmailcomLabel  matlab.ui.control.Label
        ThreshholdSlider                matlab.ui.control.Slider
        ThreshholdSliderLabel           matlab.ui.control.Label
        NegativeoverflowButtonGroup     matlab.ui.container.ButtonGroup
        Abs_SaturatedButton             matlab.ui.control.RadioButton
        ScalingButton                   matlab.ui.control.RadioButton
        SaturatedButton                 matlab.ui.control.RadioButton
        bSlider                         matlab.ui.control.Slider
        bSliderLabel                    matlab.ui.control.Label
        aSlider                         matlab.ui.control.Slider
        aSliderLabel                    matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        TvalueEditField                 matlab.ui.control.NumericEditField
        ThresholdvalueLabel             matlab.ui.control.Label
        ManualThresholdingButton        matlab.ui.control.Button
        KernelsizenxnnEditField         matlab.ui.control.NumericEditField
        KernelsizenxnnEditFieldLabel    matlab.ui.control.Label
        Label_f                         matlab.ui.control.Label
        FunctionLabel                   matlab.ui.control.Label
        Label_s                         matlab.ui.control.Label
        StatusLabel                     matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        PressLoadImagefirstLabel        matlab.ui.control.Label
        Inputkernel                     matlab.ui.control.EditField
        InputkernelEgx1245Label         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        AddnoiseDenoisewithDNNButton    matlab.ui.control.Button
        eOtsuThresholdingButton         matlab.ui.control.Button
        dGrayscaletransformationButton  matlab.ui.control.Button
        cMedianfilteringButton          matlab.ui.control.Button
        bSobelEdgeDetectionButton       matlab.ui.control.Button
        BASICIMAGEPROCESSINGANDIMAGESEGMENTATIONFUNCTIONSLabel  matlab.ui.control.Label
        aConvolutionButton              matlab.ui.control.Button
        OUTPUTLabel                     matlab.ui.control.Label
        SDE8080INDUSTRIALMACHINEVISIONLabel  matlab.ui.control.Label
        USERCOMMANDBASICLabel           matlab.ui.control.Label
        HistogramanalysisButton         matlab.ui.control.Button
        LoadimageButton                 matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = public)
        Property % Description
        x;
        W;
        H;
        img_processed;
        kernel;
        kernel_size;
    end

    methods (Access = private)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.kernel = str2num(app.Inputkernel.Value);
            app.kernel_size = app.KernelsizenxnnEditField.Value;
            app.Slider.Value=app.TvalueEditField.Value;

            set(app.UIFigure, 'WindowState', 'maximized');
        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            try
                [name,path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image files (*.jpg, *.png, *.bmp)'}, 'Select an image file');
                if isequal(name, 0) || isequal(path, 0)
                    error('No image file selected.');
                end

                pan= strcat(path,name);
                rgbImage = imread(pan);
                if size(rgbImage,3) == 3  % RGB image
                    % Convert RGB image to grayscale using the rgb2gray function
                    grayImage = rgb2gray(rgbImage);
                else  % already grayscale
                    grayImage = rgbImage;
                end

                app.x=im2double(grayImage);

                imshow(rgbImage,'Parent',app.UIAxes,'InitialMagnification', 'fit');
                x_info= imfinfo(pan);
                app.W=x_info.Width;
                app.H=x_info.Height;

                app.Label2.Visible = 'On';
                app.Label2.Text='Now press the below button to choose the desired function';
                app.PressLoadImagefirstLabel.Text='Successfully Load image';
                app.Label_f.Text='Not chosen';
                app.Label_s.Visible='Off';

            catch ME
                app.Label2.Visible = 'On';
                app.Label2.Text='Please select an image file again when choose "Load image';
                disp(ME.message);
            end
            figure(app.UIFigure);
            %
        end

        % Button pushed function: HistogramanalysisButton
        function HistogramanalysisButtonPushed(app, event)
            app.Label_f.Text='Histogram';
            app.Label_s.Visible='on';
            app.Label_s.Text='Processing ....';
            pause(0.2);
            gray_level = 255;

            img_scale= uint8(app.x*255);
            x_his= histogram_cal(img_scale,gray_level);

            app.UIAxes2.Visible = 'on';
            reset(app.UIAxes2);
            bar(x_his,'Parent',app.UIAxes2);
            title(app.UIAxes2,'Image Histogram')

            % app.UIAxes3.Visible = 'off';

            app.Label_s.Text='Done';

        end

        % Value changed function: Inputkernel
        function InputkernelValueChanged(app, event)

            app.Label.Visible = 'on';%1,2;4,5
            app.kernel = str2num(app.Inputkernel.Value);

            [k1, k2] = size(app.kernel);
            app.Label.Text='Press Convolution';

        end

        % Button pushed function: AddnoiseDenoisewithDNNButton
        function AddnoiseDenoisewithDNNButtonPushed(app, event)
            app.Label_f.Text='Add noise & Denoise with DNN';
            app.Label_s.Visible='on';
            app.Label_s.Text='Processing ....';
            pause(0.2);
            z= imnoise(app.x,'speckle');
            net=denoisingNetwork('dncnn');
            y=denoiseImage(z,net);
            title(app.UIAxes2,'Adding noise')
            title(app.UIAxes3,'Denoising image')
            imshow(app.x,'Parent',app.UIAxes,'InitialMagnification', 'fit');
            imshow(z,'Parent',app.UIAxes2,'InitialMagnification', 'fit');
            imshow(y,'Parent',app.UIAxes3,'InitialMagnification', 'fit');
            app.Label_s.Text='Done';
        end

        % Button pushed function: aConvolutionButton
        function aConvolutionButtonPushed(app, event)
            app.Label_f.Text='Convolution';
            app.Label_s.Visible='on';
            app.Label_s.Text='Processing ....';
            pause(0.2);
            conv_mode = [app.SaturatedButton.Value,app.ScalingButton.Value,app.Abs_SaturatedButton.Value];
            [img_conv,img_pad]=  conv_replicatepad(uint8(app.x*255),app.kernel, conv_mode);
            title(app.UIAxes2,'Padded Image');
            imshow(uint8(img_pad),'Parent',app.UIAxes2,'InitialMagnification', 'fit');
            title(app.UIAxes3,'Convolved Image');
            imshow(img_conv,'Parent',app.UIAxes3,'InitialMagnification', 'fit');
            app.Label_s.Text='Done';

        end

        % Value changed function: KernelsizenxnnEditField
        function KernelsizenxnnEditFieldValueChanged(app, event)
            app.kernel_size = app.KernelsizenxnnEditField.Value;

        end

        % Button pushed function: cMedianfilteringButton
        function cMedianfilteringButtonPushed(app, event)
            app.Label_f.Text='Median filter';
            app.Label_s.Visible='on';
            app.Label_s.Text='Processing ....';
            pause(0.2);
            [img_filtered,img_pad] = median_filter(app.x, app.kernel_size);
            app.UIAxes2.Visible = 'on';

            title(app.UIAxes2,'Padded Image');
            imshow(img_pad,'Parent',app.UIAxes2,'InitialMagnification', 'fit');
            app.UIAxes3.Visible = 'on';

            title(app.UIAxes3,'Median Filtered Image');
            imshow(img_filtered,'Parent',app.UIAxes3,'InitialMagnification', 'fit');
            app.Label_s.Text='Done';
        end

        % Button pushed function: eOtsuThresholdingButton
        function eOtsuThresholdingButtonPushed(app, event)
            app.Label_f.Text='Otsu method for thresholding';
            app.Label_s.Visible='on';
            app.Label_s.Text='Processing ....';
            pause(0.2);

            gray_level = 255;

            img_scale= app.x*255;
            x_his= histogram_cal(img_scale,gray_level);
            [wgz,wgz_min,T] =  Otsu_method(x_his);
            outputImage = threshold_image(app.x, T,gray_level);
            app.UIAxes3.Visible = 'on';
            title(app.UIAxes3,'Binary Image');
            imshow(outputImage,'Parent',app.UIAxes3,'InitialMagnification', 'fit');

            app.UIAxes2.Visible = 'on';
            reset(app.UIAxes2);
            plot(app.UIAxes2,1:256,wgz)
            title(app.UIAxes2,'Weighted Variance')

            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2,T,wgz_min,'r*')
            hold(app.UIAxes2, 'off');

            app.Label_s.Text=strcat('Done - T = ', num2str(T));
        end

        % Button pushed function: ManualThresholdingButton
        function ManualThresholdingButtonPushed(app, event)
            app.Label_f.Text='Manual Thresholding';
            app.Label_s.Visible='on';
            app.Label_s.Text='Processing ....';
            pause(0.2);
            gray_level=255;
            T= app.TvalueEditField.Value;
            outputImage = threshold_image(app.x, T,gray_level);
            app.UIAxes2.Visible = 'on';
            title(app.UIAxes2,'Binary Image');
            imshow(outputImage,'Parent',app.UIAxes2,'InitialMagnification', 'fit');
            app.UIAxes3.Visible = 'on';
            title(app.UIAxes3,'Binary Image');
            imshow(outputImage,'Parent',app.UIAxes3,'InitialMagnification', 'fit');
            app.Label_s.Text='Done';
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.TvalueEditField.Value = app.Slider.Value;

        end

        % Value changed function: TvalueEditField
        function TvalueEditFieldValueChanged(app, event)
            app.Slider.Value = app.TvalueEditField.Value;

        end

        % Button pushed function: dGrayscaletransformationButton
        function dGrayscaletransformationButtonPushed(app, event)
            app.Label_f.Text='Grayscale transformation';
            app.Label_s.Visible='on';
            app.Label_s.Text='Processing ....';
            pause(0.2);
            gray_level=255;
            a=app.aSlider.Value;
            b=app.bSlider.Value;
            transformed_image = grayscale_transform(app.x*255, a, b);
            app.UIAxes2.Visible = 'on';
            title(app.UIAxes2,'New Image');


            x_his= histogram_cal(transformed_image,gray_level);
            imshow(uint8(transformed_image),'Parent',app.UIAxes2,'InitialMagnification', 'fit');

            %  histogram(transformed_image); % -------------------------
            app.UIAxes3.Visible = 'on';
            reset(app.UIAxes3);
            bar(x_his,'Parent',app.UIAxes3);
            title(app.UIAxes3,'Image Histogram')


            app.Label_s.Text='Done';
        end

        % Button pushed function: bSobelEdgeDetectionButton
        function bSobelEdgeDetectionButtonPushed(app, event)
            app.Label_f.Text='Sobel Edge Detection';
            app.Label_s.Visible='on';
            app.Label_s.Text='Processing ....';
            pause(0.2);
            gray_level=255;
            conv_mode = [app.SaturatedButton.Value,app.ScalingButton.Value,app.Abs_SaturatedButton.Value];
            [edges,gradient_magnitude] = sobel_edge_cal(app.x*gray_level, app.ThreshholdSlider.Value,conv_mode);
            app.UIAxes2.Visible = 'on';
            title(app.UIAxes2,'Magtide map Image');
            imshow(uint8(gradient_magnitude),'Parent',app.UIAxes2,'InitialMagnification', 'fit');
            app.UIAxes3.Visible = 'on';
            title(app.UIAxes3,'Edge Image');
            imshow(edges,'Parent',app.UIAxes3,'InitialMagnification', 'fit');
            app.Label_s.Text='Done';

        end

        % Button pushed function: PreprocessingButton
        function PreprocessingButtonPushed(app, event)
            app.Label_f.Text='Preprocessing';
            app.Label_s.Visible='on';
            app.Label_s.Text='Processing ....';
            pause(0.2);

            if size(app.x,3) == 3  % RGB image
                % Convert RGB image to grayscale using the rgb2gray function
                app.x = rgb2gray(app.x);

            end
            app.img_processed = pre_processing (uint8(app.x*255));

            title(app.UIAxes2,'Gray Image');
            imshow(uint8(app.x*255),'Parent',app.UIAxes2,'InitialMagnification', 'fit');
            title(app.UIAxes3,'Binary Image');
            imshow(app.img_processed,'Parent',app.UIAxes3,'InitialMagnification', 'fit');
            app.Label_s.Text='Done';


        end

        % Button pushed function: ComputeformparametersandClassifyButton
        function ComputeformparametersandClassifyButtonPushed(app, event)
            app.Label_f.Text='Computeformparameters';
            app.Label_s.Visible='on';
            app.Label_s.Text='Processing ....';

            % Green_theorem_crack_code

            [m00,m01,m02,m10,m11,m20,P]= fun_Green_theorem_crack_code_4(app.img_processed);


            %Calculate central moment and invariants
            c_m20=m20-m10^2/m00;
            c_m02=m02-m01^2/m00;
            c_m11=m11-m01*m10/m00;

            phi1=c_m20+c_m02;
            phi2=(c_m20-c_m02)^2+4*c_m11^2;
            Imin=(phi1-sqrt(phi2))/2;
            Imax=(phi1+sqrt(phi2))/2;

            RI= Imin/Imax;
            psi1=phi1/m00^2;
            psi2=phi2/m00^4;
            app.Label6.Text= sprintf('%.6f',RI);
            app.Label7.Text= sprintf('%.6f',psi1);
            app.Label8.Text= sprintf('%.6f',psi2);

            Co= 4*pi*(m00/(P^2));


            %To calculate number of Holes, we flip the image and count the object with CCL

            im_0=1-app.img_processed; %flip the image
            labels= fun_connect_component_labeling(im_0);
            %  color_labels = label2rgb(labels, 'jet', 'k', 'shuffle');
            A= unique(labels);
            max(app.img_processed(:))

            %Number of holes: total segmentation - background - 1 real
            %object
            app.Label3.Text= sprintf('%.6f',length(A)-2);

            areas = zeros(length(A), 1);
            hold on;
            if (length(A)>2) % has holes
                for i = 1:length(A)
                    % Compute the actual area of object i
                    areas(i) = sum(labels(:) == A(i));
                    %Hole ratio.
                    hole_area=areas(2); %Hole area is the smaller area among 2.
                    app.Label4.Text= sprintf('%.6f',(hole_area)/m00);
                end
            else
                app.Label4.Text='No holes';
            end

            title(app.UIAxes2,'Flipped Image-CCL');
            color_labels = label2rgb(labels, 'jet', 'k', 'shuffle');
            imshow(color_labels,'Parent',app.UIAxes2,'InitialMagnification', 'fit');


            %Circularity Co= 4π * (Area / Perimeter^2)


            app.Label5.Text= sprintf('%.6f',Co);
            %Moment invariants

            app.Label_s.Text='Done';


            %Create set of normalized parameters for classification

            normalize_param = zeros (1,6); % 6 parameters
            normalize_param(1)=length(A)-2;
            if (length(A)>2)
                normalize_param(2)= (hole_area)/m00;
            else
                normalize_param(2)= 0;
            end


            if (Co>1)    normalize_param(3)= 1;
            else normalize_param(3)=Co; end
            if (RI>1)    normalize_param(4)= 1;
            else normalize_param(4)=RI; end
            if (psi1>1)    normalize_param(5)= 1;
            else normalize_param(5)=psi1; end
            if (psi2>1)    normalize_param(6)= 1;
            else normalize_param(6)=psi2; end
            app.Label3_2.Text=sprintf('%.6f',normalize_param(1));
            app.Label4_2.Text=sprintf('%.6f',normalize_param(2));
            app.Label5_2.Text=sprintf('%.6f',normalize_param(3));
            app.Label6_2.Text=sprintf('%.6f',normalize_param(4));
            app.Label7_2.Text=sprintf('%.6f',normalize_param(5));
            app.Label8_2.Text=sprintf('%.6f',normalize_param(6));

            normalize_param

            %Classification

            %pretrained parameters
            %trained_parameters=zeros(10,6);
            trained_parameters = [       1.0000    0.2529    0.6108    0.9933    0.1592    0.0000;
                1.0000    0.6838    0.5883    0.8195    0.1609    0.0003;
                1.0000    0.3831    0.5718    0.8782    0.1603    0.0001;
                1.0000    0.2120    0.5706    0.9913    0.1601    0.0000;
                0         0    0.3558    0.0858    0.3576    0.0906 ;
                0         0    0.3834    0.0806    0.3699    0.0990;
                0         0    0.5284    0.2432    0.2228    0.0184;
                0         0    0.4402    0.1145    0.3133    0.0619;
                0         0    0.3822    0.0323    0.4723    0.1960;
                0         0    0.3206    0.0352    0.5143    0.2297;];

            %data comparision
            min_dis=100;
            item=100;
            for i=1:10
                val = sum((trained_parameters(i,:)-normalize_param).^2);
                if (val< min_dis)
                    min_dis= val;
                    item=i;
                end
            end
item
            %Export the item!


            switch item
                case 1 
                    app.Label_item.Text="Washer";
                case 2 
                    app.Label_item.Text="Retain";
                case 3 
                    app.Label_item.Text="Lock";
                case 4 
                    app.Label_item.Text="Nut";
                case 5 
                    app.Label_item.Text="Screw_6";
                case 6 
                    app.Label_item.Text="Screw_5";
                case 7 
                    app.Label_item.Text="Screw_4";
                case 8 
                    app.Label_item.Text="Screw_3";
                case 9 
                    app.Label_item.Text="Screw_2";
                case 10 
                    app.Label_item.Text="Screw_1";
            end

            app.Label3_3.Text=sprintf('%.6f',trained_parameters(item,1));
            app.Label4_3.Text=sprintf('%.6f',trained_parameters(item,2));
            app.Label5_3.Text=sprintf('%.6f',trained_parameters(item,3));
            app.Label6_3.Text=sprintf('%.6f',trained_parameters(item,4));
            app.Label7_3.Text=sprintf('%.6f',trained_parameters(item,5));
            app.Label8_3.Text=sprintf('%.6f',trained_parameters(item,6));

app.Label_s.Text='Done';

        end

        % Button pushed function: ConnectedComponentLabelButton
        function ConnectedComponentLabelButtonPushed(app, event)

            app.Label_f.Text='ConnectedComponentLabel';
            app.Label_s.Visible='on';
            app.Label_s.Text='Processing ....';
            pause(0.2);

            im=app.x;
            if size(im,3) == 3  % RGB image
                % Convert RGB image to grayscale using the rgb2gray function
                im = rgb2gray(im);
            end
            level = graythresh(im);
            BW = imbinarize(im,level);

            % im = im2bw(im); % Convert to binary
            im_0=1-BW; %flip the image
            se = strel('disk', 10);
            im_0 = imclose(im_0, se);
            imshowpair(1-BW,im_0,'montage')
            labels= fun_connect_component_labeling(im_0);
            color_labels = label2rgb(labels, 'jet', 'k', 'shuffle');

            %
            %             title(app.UIAxes2,'Padded Image');
            %             imshow(labels,'Parent',app.UIAxes2,'InitialMagnification', 'fit');
            title(app.UIAxes2,'Label Image');
            imshow(color_labels,'Parent',app.UIAxes2,'InitialMagnification', 'fit');

            A= unique(labels);

            hold (app.UIAxes2,'on');
            for i = 2:length(A)
                % Compute the actual area of object i

                areas(i) = sum(labels(:) == A(i));

                % Compute the centroid of object i
                [rows, cols] = find(labels == A(i));
                centroids(i, :) = mean([cols,rows]);

                plot(app.UIAxes2,centroids(i,1), centroids(i,2),'r*');
                text(app.UIAxes2,centroids(i,1), centroids(i,2), num2str(areas(i)), 'Color', 'red');

            end
            hold (app.UIAxes2,'off');


            app.Label_s.Text='Done';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Original Image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Output data 2')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Output data 3')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.Visible = 'off';
            app.LoadimageButton = uibutton(app.GridLayout, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.HistogramanalysisButton = uibutton(app.GridLayout, 'push');
            app.HistogramanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramanalysisButtonPushed, true);
            app.HistogramanalysisButton.Text = 'Histogram analysis';
            app.USERCOMMANDBASICLabel = uilabel(app.GridLayout);
            app.USERCOMMANDBASICLabel.Text = 'FUNCTIONS';
            app.SDE8080INDUSTRIALMACHINEVISIONLabel = uilabel(app.GridLayout);
            app.SDE8080INDUSTRIALMACHINEVISIONLabel.Text = 'SDE8080 - INDUSTRIAL MACHINE VISION';
            app.OUTPUTLabel = uilabel(app.GridLayout);
            app.OUTPUTLabel.Text = '---------------------------------------------------------------------------------------------- OUTPUT ----------------------------------------------------------------------------------------------';
            app.aConvolutionButton = uibutton(app.GridLayout, 'push');
            app.aConvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @aConvolutionButtonPushed, true);
            app.aConvolutionButton.Text = 'a. Convolution';
            app.BASICIMAGEPROCESSINGANDIMAGESEGMENTATIONFUNCTIONSLabel = uilabel(app.GridLayout);
            app.BASICIMAGEPROCESSINGANDIMAGESEGMENTATIONFUNCTIONSLabel.Text = 'BASIC IMAGE PROCESSING AND IMAGE SEGMENTATION FUNCTIONS';
            app.bSobelEdgeDetectionButton = uibutton(app.GridLayout, 'push');
            app.bSobelEdgeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @bSobelEdgeDetectionButtonPushed, true);
            app.bSobelEdgeDetectionButton.Text = 'b. Sobel Edge Detection';
            app.cMedianfilteringButton = uibutton(app.GridLayout, 'push');
            app.cMedianfilteringButton.ButtonPushedFcn = createCallbackFcn(app, @cMedianfilteringButtonPushed, true);
            app.cMedianfilteringButton.Text = 'c. Median filtering';
            app.dGrayscaletransformationButton = uibutton(app.GridLayout, 'push');
            app.dGrayscaletransformationButton.ButtonPushedFcn = createCallbackFcn(app, @dGrayscaletransformationButtonPushed, true);
            app.dGrayscaletransformationButton.Text = 'd. Gray scale transformation ';
            app.eOtsuThresholdingButton = uibutton(app.GridLayout, 'push');
            app.eOtsuThresholdingButton.ButtonPushedFcn = createCallbackFcn(app, @eOtsuThresholdingButtonPushed, true);
            app.eOtsuThresholdingButton.Text = 'e. (Otsu) Thresholding ';
            app.AddnoiseDenoisewithDNNButton = uibutton(app.GridLayout, 'push');
            app.AddnoiseDenoisewithDNNButton.ButtonPushedFcn = createCallbackFcn(app, @AddnoiseDenoisewithDNNButtonPushed, true);
            app.AddnoiseDenoisewithDNNButton.Text = 'Add noise & Denoise with DNN';
            app.Label = uilabel(app.GridLayout);
            app.Label.Visible = 'off';
            app.InputkernelEgx1245Label = uilabel(app.GridLayout);
            app.InputkernelEgx1245Label.Text = {'Input kernel first '; 'Default : Average kernel '};
            app.Inputkernel = uieditfield(app.GridLayout, 'text');
            app.Inputkernel.ValueChangedFcn = createCallbackFcn(app, @InputkernelValueChanged, true);
            app.Inputkernel.Value = '1/9,1/9,1/9;1/9,1/9,1/9;1/9,1/9,1/9;';
            app.PressLoadImagefirstLabel = uilabel(app.GridLayout);
            app.PressLoadImagefirstLabel.Text = 'Press Load Image first';
            app.Label2 = uilabel(app.GridLayout);
            app.Label2.Visible = 'off';
            app.Label2.Text = 'Label2';
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Text = 'Status:';
            app.Label_s = uilabel(app.GridLayout);
            app.Label_s.Visible = 'off';
            app.Label_s.Text = 'Label_s';
            app.FunctionLabel = uilabel(app.GridLayout);
            app.FunctionLabel.Text = 'Function:';
            app.Label_f = uilabel(app.GridLayout);
            app.Label_f.Text = 'Not chosen';
            app.KernelsizenxnnEditFieldLabel = uilabel(app.GridLayout);
            app.KernelsizenxnnEditFieldLabel.Text = 'Kernel size (nxn); n= ';
            app.KernelsizenxnnEditField = uieditfield(app.GridLayout, 'numeric');
            app.KernelsizenxnnEditField.ValueChangedFcn = createCallbackFcn(app, @KernelsizenxnnEditFieldValueChanged, true);
            app.KernelsizenxnnEditField.Value = 3;
            app.ManualThresholdingButton = uibutton(app.GridLayout, 'push');
            app.ManualThresholdingButton.ButtonPushedFcn = createCallbackFcn(app, @ManualThresholdingButtonPushed, true);
            app.ManualThresholdingButton.Text = 'Manual Thresholding';
            app.ThresholdvalueLabel = uilabel(app.GridLayout);
            app.ThresholdvalueLabel.Text = 'T-value: ';
            app.TvalueEditField = uieditfield(app.GridLayout, 'numeric');
            app.TvalueEditField.ValueChangedFcn = createCallbackFcn(app, @TvalueEditFieldValueChanged, true);
            app.TvalueEditField.Value = 128;
            app.Slider = uislider(app.GridLayout);
            app.Slider.Limits = [0 255];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 120;
            app.aSliderLabel = uilabel(app.GridLayout);
            app.aSliderLabel.Text = 'a=';
            app.aSlider = uislider(app.GridLayout);
            app.aSlider.Limits = [0 255];
            app.bSliderLabel = uilabel(app.GridLayout);
            app.bSliderLabel.Text = 'b=';
            app.bSlider = uislider(app.GridLayout);
            app.bSlider.Limits = [0 255];
            app.bSlider.Value = 200;
            app.NegativeoverflowButtonGroup = uibuttongroup(app.GridLayout);
            app.NegativeoverflowButtonGroup.Title = 'Negative / overflow';
            app.SaturatedButton = uiradiobutton(app.NegativeoverflowButtonGroup);
            app.SaturatedButton.Text = 'Saturated';
            app.SaturatedButton.Value = true;
            app.ScalingButton = uiradiobutton(app.NegativeoverflowButtonGroup);
            app.ScalingButton.Text = 'Scaling';
            app.Abs_SaturatedButton = uiradiobutton(app.NegativeoverflowButtonGroup);
            app.Abs_SaturatedButton.Text = 'Abs_Saturated';
            app.ThreshholdSliderLabel = uilabel(app.GridLayout);
            app.ThreshholdSliderLabel.Text = 'Thresh hold =';
            app.ThreshholdSlider = uislider(app.GridLayout);
            app.ThreshholdSlider.Limits = [0 255];
            app.ThreshholdSlider.Value = 128;
            app.AuthorDuyAnhNguyennguyenanhduy22101998gmailcomLabel = uilabel(app.GridLayout);
            app.AuthorDuyAnhNguyennguyenanhduy22101998gmailcomLabel.Text = 'Author: Duy Anh Nguyen - nguyenanhduy22101998@gmail.com';
            app.ClassificationstepPanel = uipanel(app.GridLayout);
            app.ClassificationstepPanel.Title = 'Classification: The training data is already processed. ';
            app.PreprocessingButton = uibutton(app.ClassificationstepPanel, 'push');
            app.PreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessingButtonPushed, true);
            app.PreprocessingButton.Text = 'Pre-processing';
            app.ComputeformparametersandClassifyButton = uibutton(app.ClassificationstepPanel, 'push');
            app.ComputeformparametersandClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeformparametersandClassifyButtonPushed, true);
            app.ComputeformparametersandClassifyButton.Text = {'Compute form parameters '; 'and Classify'};
            app.noholesLabel = uilabel(app.ClassificationstepPanel);
            app.noholesLabel.Text = 'no. holes';
            app.momentinvariantsLabel = uilabel(app.ClassificationstepPanel);
            app.momentinvariantsLabel.Text = 'moment invariants,';
            app.RILabel = uilabel(app.ClassificationstepPanel);
            app.RILabel.Text = 'RI';
            app.circularityLabel = uilabel(app.ClassificationstepPanel);
            app.circularityLabel.Text = 'circularity';
            app.NormalizedLabel = uilabel(app.ClassificationstepPanel);
            app.NormalizedLabel.Text = 'Normalized';
            app.ClosestTrainingvalueLabel = uilabel(app.ClassificationstepPanel);
            app.ClosestTrainingvalueLabel.Text = 'Closest Training value';
            app.HoleTotalareaLabel = uilabel(app.ClassificationstepPanel);
            app.HoleTotalareaLabel.Text = 'Hole / Total area';
            app.Label3 = uilabel(app.ClassificationstepPanel);
            app.Label3.Text = 'NaN';
            app.Label3_2 = uilabel(app.ClassificationstepPanel);
            app.Label3_2.Text = 'NaN';
            app.Label4 = uilabel(app.ClassificationstepPanel);
            app.Label4.Text = 'NaN';
            app.Label5 = uilabel(app.ClassificationstepPanel);
            app.Label5.Text = 'NaN';
            app.Label6 = uilabel(app.ClassificationstepPanel);
            app.Label6.Text = 'NaN';
            app.Label7 = uilabel(app.ClassificationstepPanel);
            app.Label7.Text = 'NaN';
            app.Label4_2 = uilabel(app.ClassificationstepPanel);
            app.Label4_2.Text = 'NaN';
            app.Label5_2 = uilabel(app.ClassificationstepPanel);
            app.Label5_2.Text = 'NaN';
            app.Label6_2 = uilabel(app.ClassificationstepPanel);
            app.Label6_2.Text = 'NaN';
            app.Label7_2 = uilabel(app.ClassificationstepPanel);
            app.Label7_2.Text = 'NaN';
            app.ActualvalLabel = uilabel(app.ClassificationstepPanel);
            app.ActualvalLabel.Text = 'Actual val';
            app.psi1Label = uilabel(app.ClassificationstepPanel);
            app.psi1Label.Text = 'psi1';
            app.psi2Label = uilabel(app.ClassificationstepPanel);
            app.psi2Label.Text = 'psi2';
            app.Label8 = uilabel(app.ClassificationstepPanel);
            app.Label8.Text = 'NaN';
            app.Label8_2 = uilabel(app.ClassificationstepPanel);
            app.Label8_2.Text = 'NaN';
            app.Label4_3 = uilabel(app.ClassificationstepPanel);
            app.Label4_3.Text = 'NaN';
            app.Label3_3 = uilabel(app.ClassificationstepPanel);
            app.Label3_3.Text = 'NaN';
            app.Label5_3 = uilabel(app.ClassificationstepPanel);
            app.Label5_3.Text = 'NaN';
            app.Label6_3 = uilabel(app.ClassificationstepPanel);
            app.Label6_3.Text = 'NaN';
            app.Label7_3 = uilabel(app.ClassificationstepPanel);
            app.Label7_3.Text = 'NaN';
            app.Label8_3 = uilabel(app.ClassificationstepPanel);
            app.Label8_3.Text = 'NaN';
            app.Click3stepsontheleftconsecutivelytoclassifytheorginalimageLabel = uilabel(app.GridLayout);
            app.Click3stepsontheleftconsecutivelytoclassifytheorginalimageLabel.Text = {'Click 2 steps on the left consecutively '; 'to classify ONE object in the image'};
            app.ConnectedComponentLabelButton = uibutton(app.GridLayout, 'push');
            app.ConnectedComponentLabelButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectedComponentLabelButtonPushed, true);
            app.ConnectedComponentLabelButton.Text = 'Connected Component Label';
            app.THISISTHELabel = uilabel(app.GridLayout);
            app.THISISTHELabel.Text = 'THIS IS THE ';
            app.Label_item = uilabel(app.GridLayout);
            app.Label_item.Text = '...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Assignment_ENGN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end